classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        JointTeachTab                   matlab.ui.container.Tab
        JointTeachGrid                  matlab.ui.container.GridLayout
        URJointTeachParentGrid          matlab.ui.container.GridLayout
        URJointTeachPanel               matlab.ui.container.Panel
        URJointTeachChildGrid           matlab.ui.container.GridLayout
        URJointAngles                   matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout13_5                  matlab.ui.container.GridLayout
        URJoint6Spinner                 matlab.ui.control.Spinner
        Joint6SpinnerLabel              matlab.ui.control.Label
        GridLayout13_4                  matlab.ui.container.GridLayout
        URJoint5Spinner                 matlab.ui.control.Spinner
        Joint5SpinnerLabel              matlab.ui.control.Label
        GridLayout13_3                  matlab.ui.container.GridLayout
        URJoint4Spinner                 matlab.ui.control.Spinner
        Joint4SpinnerLabel              matlab.ui.control.Label
        GridLayout13_2                  matlab.ui.container.GridLayout
        URJoint3Spinner                 matlab.ui.control.Spinner
        Joint3SpinnerLabel_7            matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        Joint2SpinnerLabel_7            matlab.ui.control.Label
        URJoint2Spinner                 matlab.ui.control.Spinner
        GridLayout12                    matlab.ui.container.GridLayout
        Joint1SpinnerLabel_30           matlab.ui.control.Label
        URJoint1Spinner                 matlab.ui.control.Spinner
        URJointTeachEndEffectorTransform  matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        URJointTeachEndEffector44       matlab.ui.control.Label
        URJointTeachEndEffector43       matlab.ui.control.Label
        URJointTeachEndEffector42       matlab.ui.control.Label
        URJointTeachEndEffector41       matlab.ui.control.Label
        URJointTeachEndEffector34       matlab.ui.control.Label
        URJointTeachEndEffector33       matlab.ui.control.Label
        URJointTeachEndEffector32       matlab.ui.control.Label
        URJointTeachEndEffector31       matlab.ui.control.Label
        URJointTeachEndEffector24       matlab.ui.control.Label
        URJointTeachEndEffector23       matlab.ui.control.Label
        URJointTeachEndEffector22       matlab.ui.control.Label
        URJointTeachEndEffector21       matlab.ui.control.Label
        URJointTeachEndEffector14       matlab.ui.control.Label
        URJointTeachEndEffector13       matlab.ui.control.Label
        URJointTeachEndEffector12       matlab.ui.control.Label
        URJointTeachEndEffector11       matlab.ui.control.Label
        TM5JointTeachParentGrid         matlab.ui.container.GridLayout
        TM5JointTeachPanel              matlab.ui.container.Panel
        TM5JointTeachChildGrid          matlab.ui.container.GridLayout
        TM5JointTeachEndEffectorTransform  matlab.ui.container.Panel
        GridLayout3_3                   matlab.ui.container.GridLayout
        TR5JointTeachEndEffector44      matlab.ui.control.Label
        TR5JointTeachEndEffector43      matlab.ui.control.Label
        TR5JointTeachEndEffector42      matlab.ui.control.Label
        TR5JointTeachEndEffector41      matlab.ui.control.Label
        TR5JointTeachEndEffector34      matlab.ui.control.Label
        TR5JointTeachEndEffector33      matlab.ui.control.Label
        TR5JointTeachEndEffector32      matlab.ui.control.Label
        TR5JointTeachEndEffector31      matlab.ui.control.Label
        TR5JointTeachEndEffector24      matlab.ui.control.Label
        TR5JointTeachEndEffector23      matlab.ui.control.Label
        TR5JointTeachEndEffector22      matlab.ui.control.Label
        TR5JointTeachEndEffector21      matlab.ui.control.Label
        TR5JointTeachEndEffector14      matlab.ui.control.Label
        TR5JointTeachEndEffector13      matlab.ui.control.Label
        TR5JointTeachEndEffector12      matlab.ui.control.Label
        TR5JointTeachEndEffector11      matlab.ui.control.Label
        TM5JointAngles                  matlab.ui.container.Panel
        GridLayout11_2                  matlab.ui.container.GridLayout
        GridLayout13_10                 matlab.ui.container.GridLayout
        TM5Joint6Spinner                matlab.ui.control.Spinner
        Joint6Spinner_2Label            matlab.ui.control.Label
        GridLayout13_9                  matlab.ui.container.GridLayout
        TM5Joint5Spinner                matlab.ui.control.Spinner
        Joint5Spinner_2Label            matlab.ui.control.Label
        GridLayout13_8                  matlab.ui.container.GridLayout
        TM5Joint4Spinner                matlab.ui.control.Spinner
        Joint4Spinner_2Label            matlab.ui.control.Label
        GridLayout13_7                  matlab.ui.container.GridLayout
        TM5Joint3Spinner                matlab.ui.control.Spinner
        Joint3Spinner_2Label            matlab.ui.control.Label
        GridLayout13_6                  matlab.ui.container.GridLayout
        TM5Joint2Spinner                matlab.ui.control.Spinner
        Joint2Spinner_2Label            matlab.ui.control.Label
        GridLayout12_2                  matlab.ui.container.GridLayout
        TM5Joint1Spinner                matlab.ui.control.Spinner
        Joint1Spinner_2Label            matlab.ui.control.Label
        CartesianTeachTab               matlab.ui.container.Tab
        GridLayout_6                    matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        TM5CartesianTeachPanel          matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        TM5CartesianTeachDesiredTransform  matlab.ui.container.Panel
        GridLayout3_10                  matlab.ui.container.GridLayout
        TM5CartesianTeachDesiredTransform44  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform43  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform42  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform41  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform34  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform33  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform32  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform31  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform24  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform23  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform22  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform21  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform14  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform13  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform12  matlab.ui.control.Spinner
        TM5CartesianTeachDesiredTransform11  matlab.ui.control.Spinner
        TM5CartesianTeachEndEffectorTransform  matlab.ui.container.Panel
        GridLayout3_9                   matlab.ui.container.GridLayout
        TM5CartesianTeachEndEffectorTransform44  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform43  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform42  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform41  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform34  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform33  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform32  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform31  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform24  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform23  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform22  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform21  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform14  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform13  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform12  matlab.ui.control.Label
        TM5CartesianTeachEndEffectorTransform11  matlab.ui.control.Label
        TM5CartesianTeachStartButton    matlab.ui.control.Button
        URJointVelocitiesPanel_2        matlab.ui.container.Panel
        GridLayout10_2                  matlab.ui.container.GridLayout
        TM5CartesianTeachJointVelocity6  matlab.ui.control.Label
        TM5CartesianTeachJointVelocity5  matlab.ui.control.Label
        TM5CartesianTeachJointVelocity4  matlab.ui.control.Label
        TM5CartesianTeachJointVelocity3  matlab.ui.control.Label
        TM5CartesianTeachJointVelocity2  matlab.ui.control.Label
        TM5CartesianTeachJointVelocity1  matlab.ui.control.Label
        GridLayout2_6                   matlab.ui.container.GridLayout
        URCartesianTeachPanel           matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        URCartesianTeachDesiredTransform  matlab.ui.container.Panel
        GridLayout3_11                  matlab.ui.container.GridLayout
        URCartesianTeachDesiredTransform44  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform43  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform42  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform41  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform34  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform33  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform32  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform31  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform24  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform23  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform22  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform21  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform14  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform13  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform12  matlab.ui.control.Spinner
        URCartesianTeachDesiredTransform11  matlab.ui.control.Spinner
        URCartesianTeachEndEffectorTransform  matlab.ui.container.Panel
        GridLayout3_8                   matlab.ui.container.GridLayout
        URCartesianTeachEndEffectorTransform44  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform43  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform42  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform41  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform34  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform33  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform32  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform31  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform24  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform23  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform22  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform21  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform14  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform13  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform12  matlab.ui.control.Label
        URCartesianTeachEndEffectorTransform11  matlab.ui.control.Label
        URCartesianTeachStartButton     matlab.ui.control.Button
        URJointVelocitiesPanel          matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        URCartesianTeachJointVelocity6  matlab.ui.control.Label
        URCartesianTeachJointVelocity5  matlab.ui.control.Label
        URCartesianTeachJointVelocity4  matlab.ui.control.Label
        URCartesianTeachJointVelocity3  matlab.ui.control.Label
        URCartesianTeachJointVelocity2  matlab.ui.control.Label
        URCartesianTeachJointVelocity1  matlab.ui.control.Label
        EvEChessTab                     matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        EVEControlPanel                 matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        EVECollisonTestButton           matlab.ui.control.Button
        EVELightCurtainTestButton       matlab.ui.control.Button
        EVEeStopButton                  matlab.ui.control.Button
        EVEStartButton                  matlab.ui.control.Button
        EVEAxis                         matlab.ui.control.UIAxes
        PvEChessTab                     matlab.ui.container.Tab
        GridLayout14_2                  matlab.ui.container.GridLayout
        RealRobotControlPanel_2         matlab.ui.container.Panel
        PVERealRobotControlSwitch       matlab.ui.control.ToggleSwitch
        RealRobotControlPanel           matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        SendMoveButton                  matlab.ui.control.Button
        PVEUserInput                    matlab.ui.control.TextArea
        ControlPanel_2                  matlab.ui.container.Panel
        GridLayout15_2                  matlab.ui.container.GridLayout
        COLLISIONTESTButton_2           matlab.ui.control.Button
        LIGHTCURTAINTESTButton_2        matlab.ui.control.Button
        ESTOPButton_2                   matlab.ui.control.Button
        PVEStartButton                  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    %Internal properties
    properties (Access = public)
         chessController;
         uRJointAngles;
         tM5JointAngles;

         uREndEffectorTransformationMatrix;
         tM5EndEffectorTransformationMatrix;

         uRDesiredTransformationMatrix;
         uRDesiredTransformationFigureHandle;
         tM5DesiredTransformationMatrix;
         tM5DesiredTransformationFigureHandle;
        
         uRJointVelocities;
         tM5JointVelocities;

         %RMRC Parameters
         errorThreshold
         manipulabilityThreshold
         dampingMax
         gain
         timeStep

         uRCartesianTeachRunning
         tM5CartesianTeachRunning
        
         eVECheckerboard;

         whitePawn1Image ;
         whitePawn2Image ;
         whitePawn3Image ;
         whitePawn4Image ;
         whitePawn5Image ;
         whitePawn6Image ;
         whitePawn7Image ;
         whitePawn8Image ;
         whiteRook1Image ;
         whiteKnight1Image;
         whiteBishop1Image;
         whiteQueenImage;
         whiteKingImage;
         whiteBishop2Image;
         whiteKnight2Image;
         whiteRook2Image;

         whitePawn1Position ;
         whitePawn2Position ;
         whitePawn3Position;
         whitePawn4Position ;
         whitePawn5Position ;
         whitePawn6Position ;
         whitePawn7Position ;
         whitePawn8Position ;
         whiteRook1Position ;
         whiteKnight1Position;
         whiteBishop1Position;
         whiteQueenPosition ;
         whiteKingPosition ;
         whiteBishop2Position;
         whiteKnight2Position;
         whiteRook2Position ;

         blackPawn1Image ;
         blackPawn2Image ;
         blackPawn3Image ;
         blackPawn4Image ;
         blackPawn5Image ;
         blackPawn6Image ;
         blackPawn7Image ;
         blackPawn8Image ;
         blackRook1Image ;
         blackKnight1Image;
         blackBishop1Image;
         blackQueenImage;
         blackKingImage;
         blackBishop2Image;
         blackKnight2Image;
         blackRook2Image;
         
         blackPawn1Position ;
         blackPawn2Position ;
         blackPawn3Position;
         blackPawn4Position ;
         blackPawn5Position ;
         blackPawn6Position ;
         blackPawn7Position ;
         blackPawn8Position ;
         blackRook1Position ;
         blackKnight1Position;
         blackBishop1Position;
         blackQueenPosition ;
         blackKingPosition ;
         blackBishop2Position;
         blackKnight2Position;
         blackRook2Position ;
    end
    
    methods (Access = private)
        
        function results = setJointTeachText(app)

            app.URJointTeachEndEffector11.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(1,1));
            app.URJointTeachEndEffector12.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(1,2));
            app.URJointTeachEndEffector13.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(1,3));
            app.URJointTeachEndEffector14.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(1,4));
            app.URJointTeachEndEffector21.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(2,1));
            app.URJointTeachEndEffector22.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(2,2));
            app.URJointTeachEndEffector23.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(2,3));
            app.URJointTeachEndEffector24.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(2,4));
            app.URJointTeachEndEffector31.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(3,1));
            app.URJointTeachEndEffector32.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(3,2));
            app.URJointTeachEndEffector33.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(3,3));
            app.URJointTeachEndEffector34.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(3,4));
            app.URJointTeachEndEffector41.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(4,1));
            app.URJointTeachEndEffector42.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(4,2));
            app.URJointTeachEndEffector43.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(4,3));
            app.URJointTeachEndEffector44.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(4,4));

            app.TR5JointTeachEndEffector11.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(1,1));
            app.TR5JointTeachEndEffector12.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(1,2));
            app.TR5JointTeachEndEffector13.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(1,3));
            app.TR5JointTeachEndEffector14.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(1,4));
            app.TR5JointTeachEndEffector21.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(2,1));
            app.TR5JointTeachEndEffector22.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(2,2));
            app.TR5JointTeachEndEffector23.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(2,3));
            app.TR5JointTeachEndEffector24.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(2,4));
            app.TR5JointTeachEndEffector31.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(3,1));
            app.TR5JointTeachEndEffector32.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(3,2));
            app.TR5JointTeachEndEffector33.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(3,3));
            app.TR5JointTeachEndEffector34.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(3,4));
            app.TR5JointTeachEndEffector41.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(4,1));
            app.TR5JointTeachEndEffector42.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(4,2));
            app.TR5JointTeachEndEffector43.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(4,3));
            app.TR5JointTeachEndEffector44.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(4,4));
        end

        function results = setJointTeachSpinnerValue(app)
             
            app.URJoint1Spinner.Value = app.uRJointAngles(1,1);
            app.URJoint2Spinner.Value = app.uRJointAngles(1,2);
            app.URJoint3Spinner.Value = app.uRJointAngles(1,3);
            app.URJoint4Spinner.Value = app.uRJointAngles(1,4);
            app.URJoint5Spinner.Value = app.uRJointAngles(1,5);
            app.URJoint6Spinner.Value = app.uRJointAngles(1,6);

            app.TM5Joint1Spinner.Value = app.tM5JointAngles(1,1);
            app.TM5Joint2Spinner.Value = app.tM5JointAngles(1,2);
            app.TM5Joint3Spinner.Value = app.tM5JointAngles(1,3);
            app.TM5Joint4Spinner.Value = app.tM5JointAngles(1,4);
            app.TM5Joint5Spinner.Value = app.tM5JointAngles(1,5);
            app.TM5Joint6Spinner.Value = app.tM5JointAngles(1,6);
            
        end

        function results = setJointTeachSpinnerLimits(app)
            app.URJoint1Spinner.Limits = [app.chessController.sim.ur.model.qlim(1,1),app.chessController.sim.ur.model.qlim(1,2)]
            app.URJoint2Spinner.Limits = [app.chessController.sim.ur.model.qlim(2,1),app.chessController.sim.ur.model.qlim(2,2)]
            app.URJoint3Spinner.Limits = [app.chessController.sim.ur.model.qlim(3,1),app.chessController.sim.ur.model.qlim(3,2)]
            app.URJoint4Spinner.Limits = [app.chessController.sim.ur.model.qlim(4,1),app.chessController.sim.ur.model.qlim(4,2)]
            app.URJoint5Spinner.Limits = [app.chessController.sim.ur.model.qlim(5,1),app.chessController.sim.ur.model.qlim(5,2)]
            app.URJoint6Spinner.Limits = [app.chessController.sim.ur.model.qlim(6,1),app.chessController.sim.ur.model.qlim(6,2)]

            app.TM5Joint1Spinner.Limits = [app.chessController.sim.tm5.model.qlim(1,1),app.chessController.sim.tm5.model.qlim(1,2)]
            app.TM5Joint2Spinner.Limits = [app.chessController.sim.tm5.model.qlim(2,1),app.chessController.sim.tm5.model.qlim(2,2)]
            app.TM5Joint3Spinner.Limits = [app.chessController.sim.tm5.model.qlim(3,1),app.chessController.sim.tm5.model.qlim(3,2)]
            app.TM5Joint4Spinner.Limits = [app.chessController.sim.tm5.model.qlim(4,1),app.chessController.sim.tm5.model.qlim(4,2)]
            app.TM5Joint5Spinner.Limits = [app.chessController.sim.tm5.model.qlim(5,1),app.chessController.sim.tm5.model.qlim(5,2)]
            app.TM5Joint6Spinner.Limits = [app.chessController.sim.tm5.model.qlim(6,1),app.chessController.sim.tm5.model.qlim(6,2)]
        end

        function results = setCartesianTeachText(app)

            app.URCartesianTeachEndEffectorTransform11.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(1,1));
            app.URCartesianTeachEndEffectorTransform12.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(1,2));
            app.URCartesianTeachEndEffectorTransform13.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(1,3));
            app.URCartesianTeachEndEffectorTransform14.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(1,4));
            app.URCartesianTeachEndEffectorTransform21.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(2,1));
            app.URCartesianTeachEndEffectorTransform22.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(2,2));
            app.URCartesianTeachEndEffectorTransform23.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(2,3));
            app.URCartesianTeachEndEffectorTransform24.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(2,4));
            app.URCartesianTeachEndEffectorTransform31.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(3,1));
            app.URCartesianTeachEndEffectorTransform32.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(3,2));
            app.URCartesianTeachEndEffectorTransform33.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(3,3));
            app.URCartesianTeachEndEffectorTransform34.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(3,4));
            app.URCartesianTeachEndEffectorTransform41.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(4,1));
            app.URCartesianTeachEndEffectorTransform42.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(4,2));
            app.URCartesianTeachEndEffectorTransform43.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(4,3));
            app.URCartesianTeachEndEffectorTransform44.Text = sprintf('%0.2f',app.uREndEffectorTransformationMatrix(4,4));

            app.URCartesianTeachJointVelocity1.Text = sprintf('%0.4f',app.uRJointVelocities(1,1));
            app.URCartesianTeachJointVelocity2.Text = sprintf('%0.4f',app.uRJointVelocities(1,2));
            app.URCartesianTeachJointVelocity3.Text = sprintf('%0.4f',app.uRJointVelocities(1,3));
            app.URCartesianTeachJointVelocity4.Text = sprintf('%0.4f',app.uRJointVelocities(1,4));
            app.URCartesianTeachJointVelocity5.Text = sprintf('%0.4f',app.uRJointVelocities(1,5));
            app.URCartesianTeachJointVelocity6.Text = sprintf('%0.4f',app.uRJointVelocities(1,6));

            app.TM5CartesianTeachEndEffectorTransform11.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(1,1));
            app.TM5CartesianTeachEndEffectorTransform12.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(1,2));
            app.TM5CartesianTeachEndEffectorTransform13.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(1,3));
            app.TM5CartesianTeachEndEffectorTransform14.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(1,4));
            app.TM5CartesianTeachEndEffectorTransform21.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(2,1));
            app.TM5CartesianTeachEndEffectorTransform22.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(2,2));
            app.TM5CartesianTeachEndEffectorTransform23.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(2,3));
            app.TM5CartesianTeachEndEffectorTransform24.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(2,4));
            app.TM5CartesianTeachEndEffectorTransform31.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(3,1));
            app.TM5CartesianTeachEndEffectorTransform32.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(3,2));
            app.TM5CartesianTeachEndEffectorTransform33.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(3,3));
            app.TM5CartesianTeachEndEffectorTransform34.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(3,4));
            app.TM5CartesianTeachEndEffectorTransform41.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(4,1));
            app.TM5CartesianTeachEndEffectorTransform42.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(4,2));
            app.TM5CartesianTeachEndEffectorTransform43.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(4,3));
            app.TM5CartesianTeachEndEffectorTransform44.Text = sprintf('%0.2f',app.tM5EndEffectorTransformationMatrix(4,4));

            app.TM5CartesianTeachJointVelocity1.Text = sprintf('%0.4f',app.tM5JointVelocities(1,1));
            app.TM5CartesianTeachJointVelocity2.Text = sprintf('%0.4f',app.tM5JointVelocities(1,2));
            app.TM5CartesianTeachJointVelocity3.Text = sprintf('%0.4f',app.tM5JointVelocities(1,3));
            app.TM5CartesianTeachJointVelocity4.Text = sprintf('%0.4f',app.tM5JointVelocities(1,4));
            app.TM5CartesianTeachJointVelocity5.Text = sprintf('%0.4f',app.tM5JointVelocities(1,5));
            app.TM5CartesianTeachJointVelocity6.Text = sprintf('%0.4f',app.tM5JointVelocities(1,6));
        end

        function results = setCartesianTeachSpinnerValue(app)
            app.URCartesianTeachDesiredTransform11.Value = app.uRDesiredTransformationMatrix(1,1);
            app.URCartesianTeachDesiredTransform12.Value = app.uRDesiredTransformationMatrix(1,2);
            app.URCartesianTeachDesiredTransform13.Value = app.uRDesiredTransformationMatrix(1,3);
            app.URCartesianTeachDesiredTransform14.Value = app.uRDesiredTransformationMatrix(1,4);
            app.URCartesianTeachDesiredTransform21.Value = app.uRDesiredTransformationMatrix(2,1);
            app.URCartesianTeachDesiredTransform22.Value = app.uRDesiredTransformationMatrix(2,2);
            app.URCartesianTeachDesiredTransform23.Value = app.uRDesiredTransformationMatrix(2,3);
            app.URCartesianTeachDesiredTransform24.Value = app.uRDesiredTransformationMatrix(2,4);
            app.URCartesianTeachDesiredTransform31.Value = app.uRDesiredTransformationMatrix(3,1);
            app.URCartesianTeachDesiredTransform32.Value = app.uRDesiredTransformationMatrix(3,2);
            app.URCartesianTeachDesiredTransform33.Value = app.uRDesiredTransformationMatrix(3,3);
            app.URCartesianTeachDesiredTransform34.Value = app.uRDesiredTransformationMatrix(3,4);
            app.URCartesianTeachDesiredTransform41.Value = app.uRDesiredTransformationMatrix(4,1);
            app.URCartesianTeachDesiredTransform42.Value = app.uRDesiredTransformationMatrix(4,2);
            app.URCartesianTeachDesiredTransform43.Value = app.uRDesiredTransformationMatrix(4,3);
            app.URCartesianTeachDesiredTransform44.Value = app.uRDesiredTransformationMatrix(4,4);

            app.TM5CartesianTeachDesiredTransform11.Value = app.tM5DesiredTransformationMatrix(1,1);
            app.TM5CartesianTeachDesiredTransform12.Value = app.tM5DesiredTransformationMatrix(1,2);
            app.TM5CartesianTeachDesiredTransform13.Value = app.tM5DesiredTransformationMatrix(1,3);
            app.TM5CartesianTeachDesiredTransform14.Value = app.tM5DesiredTransformationMatrix(1,4);
            app.TM5CartesianTeachDesiredTransform21.Value = app.tM5DesiredTransformationMatrix(2,1);
            app.TM5CartesianTeachDesiredTransform22.Value = app.tM5DesiredTransformationMatrix(2,2);
            app.TM5CartesianTeachDesiredTransform23.Value = app.tM5DesiredTransformationMatrix(2,3);
            app.TM5CartesianTeachDesiredTransform24.Value = app.tM5DesiredTransformationMatrix(2,4);
            app.TM5CartesianTeachDesiredTransform31.Value = app.tM5DesiredTransformationMatrix(3,1);
            app.TM5CartesianTeachDesiredTransform32.Value = app.tM5DesiredTransformationMatrix(3,2);
            app.TM5CartesianTeachDesiredTransform33.Value = app.tM5DesiredTransformationMatrix(3,3);
            app.TM5CartesianTeachDesiredTransform34.Value = app.tM5DesiredTransformationMatrix(3,4);
            app.TM5CartesianTeachDesiredTransform41.Value = app.tM5DesiredTransformationMatrix(4,1);
            app.TM5CartesianTeachDesiredTransform42.Value = app.tM5DesiredTransformationMatrix(4,2);
            app.TM5CartesianTeachDesiredTransform43.Value = app.tM5DesiredTransformationMatrix(4,3);
            app.TM5CartesianTeachDesiredTransform44.Value = app.tM5DesiredTransformationMatrix(4,4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            clf
            app.chessController = ChessController(0);
            %Set Starting Values for Joint Teach

            app.uRJointAngles = app.chessController.sim.ur.model.getpos();
            app.tM5JointAngles = app.chessController.sim.tm5.model.getpos();

            app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
            app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
            
            app.uRDesiredTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
            app.tM5DesiredTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
            
            app.uRJointVelocities = [0,0,0,0,0,0];
            app.tM5JointVelocities = [0,0,0,0,0,0];

            app.errorThreshold = 0.001;
            app.gain = 0.1;
            app.timeStep = 0.05;
            app.manipulabilityThreshold = 0.001;
            app.dampingMax = 0.1;

            app.eVECheckerboard = checkerboard(80);
            app.whitePawn1Image = imread("gui_resources/white pawn.png");
            app.whitePawn2Image = imread("gui_resources/white pawn.png");
            app.whitePawn3Image = imread("gui_resources/white pawn.png");
            app.whitePawn4Image = imread("gui_resources/white pawn.png");
            app.whitePawn5Image = imread("gui_resources/white pawn.png");
            app.whitePawn6Image = imread("gui_resources/white pawn.png");
            app.whitePawn7Image = imread("gui_resources/white pawn.png");
            app.whitePawn8Image = imread("gui_resources/white pawn.png");
            app.whiteRook1Image = imread("gui_resources/white rook.png");
            app.whiteKnight1Image = imread("gui_resources/white knight.png");
            app.whiteBishop1Image = imread("gui_resources/white bishop.png");
            app.whiteQueenImage = imread("gui_resources/white queen.png");
            app.whiteKingImage = imread("gui_resources/white king.png");
            app.whiteBishop2Image = imread("gui_resources/white bishop.png");
            app.whiteKnight2Image = imread("gui_resources/white knight.png");
            app.whiteRook2Image = imread("gui_resources/white rook.png");

            app.whitePawn1Position = [0,0];
            app.whitePawn2Position = [0,0];
            app.whitePawn3Position = [0,0];
            app.whitePawn4Position = [0,0];
            app.whitePawn5Position = [0,0];
            app.whitePawn6Position = [0,0];
            app.whitePawn7Position = [0,0];
            app.whitePawn8Position = [0,0];
            app.whiteRook1Position = [0,0];
            app.whiteKnight1Position = [0,0];
            app.whiteBishop1Position = [0,0];
            app.whiteQueenPosition = [0,0];
            app.whiteKingPosition = [0,0];
            app.whiteBishop2Position = [0,0];
            app.whiteKnight2Position = [0,0];
            app.whiteRook2Position = [0,0];

            app.blackPawn1Image = imread("gui_resources/black pawn.png");
            app.blackPawn2Image = imread("gui_resources/black pawn.png");
            app.blackPawn3Image = imread("gui_resources/black pawn.png");
            app.blackPawn4Image = imread("gui_resources/black pawn.png");
            app.blackPawn5Image = imread("gui_resources/black pawn.png");
            app.blackPawn6Image = imread("gui_resources/black pawn.png");
            app.blackPawn7Image = imread("gui_resources/black pawn.png");
            app.blackPawn8Image = imread("gui_resources/black pawn.png");
    
            app.uRCartesianTeachRunning = 0;
            app.tM5CartesianTeachRunning = 0;

            app.setJointTeachSpinnerValue();
            app.setJointTeachSpinnerLimits();
            app.setJointTeachText();
            app.setCartesianTeachText();
            app.setCartesianTeachSpinnerValue();
        end

        % Value changed function: URJoint1Spinner
        function URJoint1SpinnerValueChanged(app, event)
            value = app.URJoint1Spinner.Value;
            app.uRJointAngles(1,1) = value;
            app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.ur.model.animate(app.uRJointAngles);
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
        end

        % Value changed function: URJoint2Spinner
        function URJoint2SpinnerValueChanged(app, event)
            value = app.URJoint2Spinner.Value;
            app.uRJointAngles(1,2) = value;
            app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.ur.model.animate(app.uRJointAngles);
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
           
        end

        % Value changed function: URJoint3Spinner
        function URJoint3SpinnerValueChanged(app, event)
            value = app.URJoint3Spinner.Value;
            app.uRJointAngles(1,3) = value;
            app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.ur.model.animate(app.uRJointAngles);
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
        end

        % Value changed function: URJoint4Spinner
        function URJoint4SpinnerValueChanged(app, event)
            value = app.URJoint4Spinner.Value;
            app.uRJointAngles(1,4) = value;
            app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.ur.model.animate(app.uRJointAngles);
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
        end

        % Value changed function: URJoint5Spinner
        function URJoint5SpinnerValueChanged(app, event)
            value = app.URJoint5Spinner.Value;
            app.uRJointAngles(1,5) = value;
            app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.ur.model.animate(app.uRJointAngles);
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
        end

        % Value changed function: URJoint6Spinner
        function URJoint6SpinnerValueChanged(app, event)
            value = app.URJoint6Spinner.Value;
            app.uRJointAngles(1,6) = value;
            app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.ur.model.animate(app.uRJointAngles);
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
            app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
        end

        % Value changed function: TM5Joint1Spinner
        function TM5Joint1SpinnerValueChanged(app, event)
            value = app.TM5Joint1Spinner.Value;
            app.tM5JointAngles(1,1) = value;
            app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.tm5.model.animate(app.tM5JointAngles);
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
        end

        % Value changed function: TM5Joint2Spinner
        function TM5Joint2SpinnerValueChanged(app, event)
            value = app.TM5Joint2Spinner.Value;
            app.tM5JointAngles(1,2) = value;
            app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.tm5.model.animate(app.tM5JointAngles);
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
        end

        % Value changed function: TM5Joint3Spinner
        function TM5Joint3SpinnerValueChanged(app, event)
            value = app.TM5Joint3Spinner.Value;
            app.tM5JointAngles(1,3) = value;
            app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.tm5.model.animate(app.tM5JointAngles);
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
        end

        % Value changed function: TM5Joint4Spinner
        function TM5Joint4SpinnerValueChanged(app, event)
            value = app.TM5Joint4Spinner.Value;
            app.tM5JointAngles(1,4) = value;
            app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.tm5.model.animate(app.tM5JointAngles);
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
        end

        % Value changed function: TM5Joint5Spinner
        function TM5Joint5SpinnerValueChanged(app, event)
            value = app.TM5Joint5Spinner.Value;
            app.tM5JointAngles(1,5) = value;
            app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.tm5.model.animate(app.tM5JointAngles);
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
        end

        % Value changed function: TM5Joint6Spinner
        function TM5Joint6SpinnerValueChanged(app, event)
            value = app.TM5Joint6Spinner.Value;
            app.tM5JointAngles(1,6) = value;
            app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
            app.setJointTeachText();
            app.chessController.sim.tm5.model.animate(app.tM5JointAngles);
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
            app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
        end

        % Callback function
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            delete(app.uRDesiredTransformationFigureHandle);
            delete(app.tR5DesiredTransformationFigureHandle);
        end

        % Value changed function: URCartesianTeachDesiredTransform11
        function URCartesianTeachDesiredTransform11ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform11.Value;
            app.uRDesiredTransformationMatrix(1,1) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform12
        function URCartesianTeachDesiredTransform12ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform12.Value;
            app.uRDesiredTransformationMatrix(1,2) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform13
        function URCartesianTeachDesiredTransform13ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform13.Value;
            app.uRDesiredTransformationMatrix(1,3) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform14
        function URCartesianTeachDesiredTransform14ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform14.Value;
            app.uRDesiredTransformationMatrix(1,4) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform21
        function URCartesianTeachDesiredTransform21ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform21.Value;
            app.uRDesiredTransformationMatrix(2,1) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform22
        function URCartesianTeachDesiredTransform22ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform22.Value;
            app.uRDesiredTransformationMatrix(2,2) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform23
        function URCartesianTeachDesiredTransform23ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform23.Value;
            app.uRDesiredTransformationMatrix(2,3) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform24
        function URCartesianTeachDesiredTransform24ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform24.Value;
            app.uRDesiredTransformationMatrix(2,4) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform31
        function URCartesianTeachDesiredTransform31ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform31.Value;
            app.uRDesiredTransformationMatrix(3,1) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform32
        function URCartesianTeachDesiredTransform32ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform32.Value;
            app.uRDesiredTransformationMatrix(3,2) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform33
        function URCartesianTeachDesiredTransform33ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform33.Value;
            app.uRDesiredTransformationMatrix(3,3) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform34
        function URCartesianTeachDesiredTransform34ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform34.Value;
            app.uRDesiredTransformationMatrix(3,4) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform41
        function URCartesianTeachDesiredTransform41ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform41.Value;
            app.uRDesiredTransformationMatrix(4,1) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform42
        function URCartesianTeachDesiredTransform42ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform42.Value;
            app.uRDesiredTransformationMatrix(4,2) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform43
        function URCartesianTeachDesiredTransform43ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform43.Value;
            app.uRDesiredTransformationMatrix(4,3) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Value changed function: URCartesianTeachDesiredTransform44
        function URCartesianTeachDesiredTransform44ValueChanged(app, event)
            value = app.URCartesianTeachDesiredTransform44.Value;
            app.uRDesiredTransformationMatrix(4,4) = value;
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRDesiredTransformationFigureHandle = trplot(app.uRDesiredTransformationMatrix,'color','g');
        end

        % Button pushed function: URCartesianTeachStartButton
        function URCartesianTeachStartButtonPushed(app, event)
            normal = 100;
            app.uRCartesianTeachRunning = ~app.uRCartesianTeachRunning;

            while normal > app.errorThreshold && app.uRCartesianTeachRunning == 1
                app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;

                desiredTransformInEndEffector =  inv(app.uREndEffectorTransformationMatrix) * app.uRDesiredTransformationMatrix;
                desiredQuatInEndEffector = tform2quat(desiredTransformInEndEffector);
  
                endEffectorVelocity(1,1) = app.gain * desiredTransformInEndEffector(1,4);
                endEffectorVelocity(2,1) = app.gain * desiredTransformInEndEffector(2,4);
                endEffectorVelocity(3,1) = app.gain * desiredTransformInEndEffector(3,4);
                endEffectorVelocity(4,1) = app.gain * desiredQuatInEndEffector(1,2);
                endEffectorVelocity(5,1) = app.gain * desiredQuatInEndEffector(1,3);
                endEffectorVelocity(6,1) = app.gain * desiredQuatInEndEffector(1,4);

                jacobian = app.chessController.sim.ur.model.jacobe(app.uRJointAngles);

                mu = app.chessController.sim.ur.model.maniplty(app.uRJointAngles,'yoshikawa');
   
                if mu > app.manipulabilityThreshold
                   damp = 0
                else
                   damp = 1 - (mu/app.manipulabilityThreshold)^2 * app.dampingMax;
                end

                psuedoInverseJacobian = jacobian' * inv(jacobian*jacobian' + damp * eye(6));

                app.uRJointVelocities = psuedoInverseJacobian * endEffectorVelocity;
                
                app.uRJointAngles = app.uRJointAngles + app.timeStep*app.uRJointVelocities';
                app.uREndEffectorTransformationMatrix = app.chessController.sim.ur.model.fkine(app.uRJointAngles).T;
                app.chessController.sim.ur.model.animate(app.uRJointAngles);
                app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));
                app.chessController.sim.ur.gripper.UpdateBase(app.chessController.sim.ur.model.fkine(app.uRJointAngles));

                app.uRJointVelocities = app.uRJointVelocities';
             
                app.setCartesianTeachText();
                normal = norm(endEffectorVelocity);
                
                app.URCartesianTeachStartButton.Text = sprintf("%s %f","Euclidian Error:",normal);
                app.URCartesianTeachStartButton.BackgroundColor = "#FFA500";

                drawnow();
            end
            delete(app.uRDesiredTransformationFigureHandle);
            app.uRCartesianTeachRunning = 0;
            app.URCartesianTeachStartButton.Text = sprintf("%s %f","Start");
            app.URCartesianTeachStartButton.BackgroundColor = [0.47,0.67,0.19];
        end

        % Value changed function: TM5CartesianTeachDesiredTransform11
        function TM5CartesianTeachDesiredTransform11ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform11.Value;
            app.tM5DesiredTransformationMatrix(1,1) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform12
        function TM5CartesianTeachDesiredTransform12ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform12.Value;
            app.tM5DesiredTransformationMatrix(1,2) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform13
        function TM5CartesianTeachDesiredTransform13ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform13.Value;
            app.tM5DesiredTransformationMatrix(1,3) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform14
        function TM5CartesianTeachDesiredTransform14ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform14.Value;
            app.tM5DesiredTransformationMatrix(1,4) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform21
        function TM5CartesianTeachDesiredTransform21ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform21.Value;
            app.tM5DesiredTransformationMatrix(2,1) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform22
        function TM5CartesianTeachDesiredTransform22ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform22.Value;
            app.tM5DesiredTransformationMatrix(2,2) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform23
        function TM5CartesianTeachDesiredTransform23ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform23.Value;
            app.tM5DesiredTransformationMatrix(2,3) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform24
        function TM5CartesianTeachDesiredTransform24ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform24.Value;
            app.tM5DesiredTransformationMatrix(2,4) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform31
        function TM5CartesianTeachDesiredTransform31ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform31.Value;
            app.tM5DesiredTransformationMatrix(3,1) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform32
        function TM5CartesianTeachDesiredTransform32ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform32.Value;
            app.tM5DesiredTransformationMatrix(3,2) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform33
        function TM5CartesianTeachDesiredTransform33ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform33.Value;
            app.tM5DesiredTransformationMatrix(3,3) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform34
        function TM5CartesianTeachDesiredTransform34ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform34.Value;
            app.tM5DesiredTransformationMatrix(3,4) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform41
        function TM5CartesianTeachDesiredTransform41ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform41.Value;
            app.tM5DesiredTransformationMatrix(4,1) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform42
        function TM5CartesianTeachDesiredTransform42ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform42.Value;
            app.tM5DesiredTransformationMatrix(4,2) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform43
        function TM5CartesianTeachDesiredTransform43ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform43.Value;
            app.tM5DesiredTransformationMatrix(4,3) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Value changed function: TM5CartesianTeachDesiredTransform44
        function TM5CartesianTeachDesiredTransform44ValueChanged(app, event)
            value = app.TM5CartesianTeachDesiredTransform44.Value;
            app.tM5DesiredTransformationMatrix(4,4) = value;
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5DesiredTransformationFigureHandle = trplot(app.tM5DesiredTransformationMatrix,'color','b');
        end

        % Button pushed function: TM5CartesianTeachStartButton
        function TM5CartesianTeachStartButtonPushed(app, event)
            normal = 100;
            app.tM5CartesianTeachRunning = ~app.tM5CartesianTeachRunning;

            while normal > app.errorThreshold && app.tM5CartesianTeachRunning == 1
     
                app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
                desiredTransformInEndEffector =  inv(app.tM5EndEffectorTransformationMatrix) * app.tM5DesiredTransformationMatrix;
                desiredQuatInEndEffector = tform2quat(desiredTransformInEndEffector);
  
                endEffectorVelocity(1,1) = app.gain * desiredTransformInEndEffector(1,4);
                endEffectorVelocity(2,1) = app.gain * desiredTransformInEndEffector(2,4);
                endEffectorVelocity(3,1) = app.gain * desiredTransformInEndEffector(3,4);
                endEffectorVelocity(4,1) = app.gain * desiredQuatInEndEffector(1,2);
                endEffectorVelocity(5,1) = app.gain * desiredQuatInEndEffector(1,3);
                endEffectorVelocity(6,1) = app.gain * desiredQuatInEndEffector(1,4);

                jacobian = app.chessController.sim.tm5.model.jacobe(app.tM5JointAngles);

                dampMax = 0.1;

                mu = app.chessController.sim.tm5.model.maniplty(app.tM5JointAngles,'yoshikawa');
   
            
                if mu > app.manipulabilityThreshold
                   damp = 0;
                else
                   damp = 1 - (mu/app.manipulabilityThreshold)^2 * app.dampingMax;
                end
                psuedoInverseJacobian = jacobian' * inv(jacobian*jacobian' + damp * eye(6));

                app.tM5JointVelocities = psuedoInverseJacobian * endEffectorVelocity;
                
                app.tM5JointAngles = app.tM5JointAngles + app.timeStep*app.tM5JointVelocities';
                app.tM5EndEffectorTransformationMatrix = app.chessController.sim.tm5.model.fkine(app.tM5JointAngles).T;
                app.chessController.sim.tm5.model.animate(app.tM5JointAngles);
                app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
                app.chessController.sim.tm5.gripper.UpdateBase(app.chessController.sim.tm5.model.fkine(app.tM5JointAngles));
                app.tM5JointVelocities = app.tM5JointVelocities'
                app.setCartesianTeachText();
                normal = norm(endEffectorVelocity);
                app.TM5CartesianTeachStartButton.Text = sprintf("%s %f","Euclidian Error:",normal);
                app.TM5CartesianTeachStartButton.BackgroundColor = "#FFA500";
                drawnow();
            end
            delete(app.tM5DesiredTransformationFigureHandle);
            app.tM5CartesianTeachRunning = 0;
            app.TM5CartesianTeachStartButton.Text = sprintf("%s %f","Start");
            app.TM5CartesianTeachStartButton.BackgroundColor = [0.47,0.67,0.19];
        end

        % Button pushed function: EVEStartButton
        function EVEStartButtonPushed(app, event)

            app.chessController.chessGameEvE()
        end

        % Button pushed function: EVEeStopButton
        function EVEeStopButtonPushed(app, event)

        end

        % Button pushed function: EVELightCurtainTestButton
        function EVELightCurtainTestButtonPushed(app, event)
            
        end

        % Button pushed function: EVECollisonTestButton
        function EVECollisonTestButtonPushed(app, event)
            
        end

        % Button pushed function: SendMoveButton
        function SendMoveButtonPushed(app, event)
            userMove = app.PVEUserInput.Value;
            disp(["Front end recieved",userMove])
            app.chessController.newMove = userMove;
            app.chessController.playerMadeNewMove = 1;
            app.chessController.increment = app.chessController.increment + 1;
            disp(["Increment:"],num2str(app.chessController.increment));
        end

        % Button pushed function: PVEStartButton
        function PVEStartButtonPushed(app, event)
            realRobotEnabled = app.PVERealRobotControlSwitch.Value;
            realRobotEnabledBool = 0;
            if realRobotEnabled == "Off"
                realRobotEnabledBool = 0;
            else
                realRobotEnabledBool = 1;
            end
            app.chessController.chessGamePvE(realRobotEnabledBool);
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged2(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if selectedTab.Title == "EvE Chess"
                imshow(app.eVECheckerboard, 'Parent', app.EVEAxis);
                
                %While this tab is selected keep updating the board
                while true
                    %Plot the white pawns
                    hold on
                    imshow(app.whitePawn1Image,'Parent', app.EVEAxis,'XData',[0,0.05],'YData',[0,0.05])
                    
                    %Allow for callbacks
                    pause(2);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged2, true);
            app.JointTeachTab = uitab(app.TabGroup);
            app.JointTeachTab.Title = 'Joint Teach';
            app.JointTeachGrid = uigridlayout(app.JointTeachTab);
            app.TM5JointTeachParentGrid = uigridlayout(app.JointTeachGrid);
            app.TM5JointTeachPanel = uipanel(app.TM5JointTeachParentGrid);
            app.TM5JointTeachPanel.Title = 'TM5';
            app.TM5JointTeachChildGrid = uigridlayout(app.TM5JointTeachPanel);
            app.TM5JointAngles = uipanel(app.TM5JointTeachChildGrid);
            app.TM5JointAngles.Title = 'Joint Angles (Rad)';
            app.GridLayout11_2 = uigridlayout(app.TM5JointAngles);
            app.GridLayout12_2 = uigridlayout(app.GridLayout11_2);
            app.Joint1Spinner_2Label = uilabel(app.GridLayout12_2);
            app.Joint1Spinner_2Label.Text = 'Joint 1';
            app.TM5Joint1Spinner = uispinner(app.GridLayout12_2);
            app.TM5Joint1Spinner.Step = 0.01;
            app.TM5Joint1Spinner.ValueDisplayFormat = '%0.4f';
            app.TM5Joint1Spinner.ValueChangedFcn = createCallbackFcn(app, @TM5Joint1SpinnerValueChanged, true);
            app.GridLayout13_6 = uigridlayout(app.GridLayout11_2);
            app.Joint2Spinner_2Label = uilabel(app.GridLayout13_6);
            app.Joint2Spinner_2Label.Text = 'Joint 2';
            app.TM5Joint2Spinner = uispinner(app.GridLayout13_6);
            app.TM5Joint2Spinner.Step = 0.01;
            app.TM5Joint2Spinner.ValueDisplayFormat = '%0.4f';
            app.TM5Joint2Spinner.ValueChangedFcn = createCallbackFcn(app, @TM5Joint2SpinnerValueChanged, true);
            app.GridLayout13_7 = uigridlayout(app.GridLayout11_2);
            app.Joint3Spinner_2Label = uilabel(app.GridLayout13_7);
            app.Joint3Spinner_2Label.Text = 'Joint 3';
            app.TM5Joint3Spinner = uispinner(app.GridLayout13_7);
            app.TM5Joint3Spinner.Step = 0.01;
            app.TM5Joint3Spinner.ValueDisplayFormat = '%0.4f';
            app.TM5Joint3Spinner.ValueChangedFcn = createCallbackFcn(app, @TM5Joint3SpinnerValueChanged, true);
            app.GridLayout13_8 = uigridlayout(app.GridLayout11_2);
            app.Joint4Spinner_2Label = uilabel(app.GridLayout13_8);
            app.Joint4Spinner_2Label.Text = 'Joint 4';
            app.TM5Joint4Spinner = uispinner(app.GridLayout13_8);
            app.TM5Joint4Spinner.Step = 0.01;
            app.TM5Joint4Spinner.ValueDisplayFormat = '%0.4f';
            app.TM5Joint4Spinner.ValueChangedFcn = createCallbackFcn(app, @TM5Joint4SpinnerValueChanged, true);
            app.GridLayout13_9 = uigridlayout(app.GridLayout11_2);
            app.Joint5Spinner_2Label = uilabel(app.GridLayout13_9);
            app.Joint5Spinner_2Label.Text = 'Joint 5';
            app.TM5Joint5Spinner = uispinner(app.GridLayout13_9);
            app.TM5Joint5Spinner.Step = 0.01;
            app.TM5Joint5Spinner.ValueDisplayFormat = '%0.4f';
            app.TM5Joint5Spinner.ValueChangedFcn = createCallbackFcn(app, @TM5Joint5SpinnerValueChanged, true);
            app.GridLayout13_10 = uigridlayout(app.GridLayout11_2);
            app.Joint6Spinner_2Label = uilabel(app.GridLayout13_10);
            app.Joint6Spinner_2Label.Text = 'Joint 6';
            app.TM5Joint6Spinner = uispinner(app.GridLayout13_10);
            app.TM5Joint6Spinner.Step = 0.01;
            app.TM5Joint6Spinner.ValueDisplayFormat = '%0.4f';
            app.TM5Joint6Spinner.ValueChangedFcn = createCallbackFcn(app, @TM5Joint6SpinnerValueChanged, true);
            app.TM5JointTeachEndEffectorTransform = uipanel(app.TM5JointTeachChildGrid);
            app.TM5JointTeachEndEffectorTransform.Title = 'End Effector Transform';
            app.GridLayout3_3 = uigridlayout(app.TM5JointTeachEndEffectorTransform);
            app.TR5JointTeachEndEffector11 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector11.Text = 'Loading';
            app.TR5JointTeachEndEffector12 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector12.Text = 'Loading';
            app.TR5JointTeachEndEffector13 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector13.Text = 'Loading';
            app.TR5JointTeachEndEffector14 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector14.Text = 'Loading';
            app.TR5JointTeachEndEffector21 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector21.Text = 'Loading';
            app.TR5JointTeachEndEffector22 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector22.Text = 'Loading';
            app.TR5JointTeachEndEffector23 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector23.Text = 'Loading';
            app.TR5JointTeachEndEffector24 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector24.Text = 'Loading';
            app.TR5JointTeachEndEffector31 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector31.Text = 'Loading';
            app.TR5JointTeachEndEffector32 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector32.Text = 'Loading';
            app.TR5JointTeachEndEffector33 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector33.Text = 'Loading';
            app.TR5JointTeachEndEffector34 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector34.Text = 'Loading';
            app.TR5JointTeachEndEffector41 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector41.Text = 'Loading';
            app.TR5JointTeachEndEffector42 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector42.Text = 'Loading';
            app.TR5JointTeachEndEffector43 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector43.Text = 'Loading';
            app.TR5JointTeachEndEffector44 = uilabel(app.GridLayout3_3);
            app.TR5JointTeachEndEffector44.Text = 'Loading';
            app.URJointTeachParentGrid = uigridlayout(app.JointTeachGrid);
            app.URJointTeachPanel = uipanel(app.URJointTeachParentGrid);
            app.URJointTeachPanel.Title = 'UR3';
            app.URJointTeachChildGrid = uigridlayout(app.URJointTeachPanel);
            app.URJointTeachEndEffectorTransform = uipanel(app.URJointTeachChildGrid);
            app.URJointTeachEndEffectorTransform.Title = 'End Effector Transform';
            app.GridLayout3_2 = uigridlayout(app.URJointTeachEndEffectorTransform);
            app.URJointTeachEndEffector11 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector11.Text = 'Loading';
            app.URJointTeachEndEffector12 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector12.Text = 'Loading';
            app.URJointTeachEndEffector13 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector13.Text = 'Loading';
            app.URJointTeachEndEffector14 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector14.Text = 'Loading';
            app.URJointTeachEndEffector21 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector21.Text = 'Loading';
            app.URJointTeachEndEffector22 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector22.Text = 'Loading';
            app.URJointTeachEndEffector23 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector23.Text = 'Loading';
            app.URJointTeachEndEffector24 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector24.Text = 'Loading';
            app.URJointTeachEndEffector31 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector31.Text = 'Loading';
            app.URJointTeachEndEffector32 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector32.Text = 'Loading';
            app.URJointTeachEndEffector33 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector33.Text = 'Loading';
            app.URJointTeachEndEffector34 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector34.Text = 'Loading';
            app.URJointTeachEndEffector41 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector41.Text = 'Loading';
            app.URJointTeachEndEffector42 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector42.Text = 'Loading';
            app.URJointTeachEndEffector43 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector43.Text = 'Loading';
            app.URJointTeachEndEffector44 = uilabel(app.GridLayout3_2);
            app.URJointTeachEndEffector44.Text = 'Loading';
            app.URJointAngles = uipanel(app.URJointTeachChildGrid);
            app.URJointAngles.Title = 'Joint Angles (Rad)';
            app.GridLayout11 = uigridlayout(app.URJointAngles);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.URJoint1Spinner = uispinner(app.GridLayout12);
            app.URJoint1Spinner.Step = 0.01;
            app.URJoint1Spinner.ValueDisplayFormat = '%0.4f';
            app.URJoint1Spinner.ValueChangedFcn = createCallbackFcn(app, @URJoint1SpinnerValueChanged, true);
            app.Joint1SpinnerLabel_30 = uilabel(app.GridLayout12);
            app.Joint1SpinnerLabel_30.Text = 'Joint 1';
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.URJoint2Spinner = uispinner(app.GridLayout13);
            app.URJoint2Spinner.Step = 0.01;
            app.URJoint2Spinner.ValueDisplayFormat = '%0.4f';
            app.URJoint2Spinner.ValueChangedFcn = createCallbackFcn(app, @URJoint2SpinnerValueChanged, true);
            app.Joint2SpinnerLabel_7 = uilabel(app.GridLayout13);
            app.Joint2SpinnerLabel_7.Text = 'Joint 2';
            app.GridLayout13_2 = uigridlayout(app.GridLayout11);
            app.Joint3SpinnerLabel_7 = uilabel(app.GridLayout13_2);
            app.Joint3SpinnerLabel_7.Text = 'Joint 3';
            app.URJoint3Spinner = uispinner(app.GridLayout13_2);
            app.URJoint3Spinner.Step = 0.01;
            app.URJoint3Spinner.ValueDisplayFormat = '%0.4f';
            app.URJoint3Spinner.ValueChangedFcn = createCallbackFcn(app, @URJoint3SpinnerValueChanged, true);
            app.GridLayout13_3 = uigridlayout(app.GridLayout11);
            app.Joint4SpinnerLabel = uilabel(app.GridLayout13_3);
            app.Joint4SpinnerLabel.Text = 'Joint 4';
            app.URJoint4Spinner = uispinner(app.GridLayout13_3);
            app.URJoint4Spinner.Step = 0.01;
            app.URJoint4Spinner.ValueDisplayFormat = '%0.4f';
            app.URJoint4Spinner.ValueChangedFcn = createCallbackFcn(app, @URJoint4SpinnerValueChanged, true);
            app.GridLayout13_4 = uigridlayout(app.GridLayout11);
            app.Joint5SpinnerLabel = uilabel(app.GridLayout13_4);
            app.Joint5SpinnerLabel.Text = 'Joint 5';
            app.URJoint5Spinner = uispinner(app.GridLayout13_4);
            app.URJoint5Spinner.Step = 0.01;
            app.URJoint5Spinner.ValueDisplayFormat = '%0.4f';
            app.URJoint5Spinner.ValueChangedFcn = createCallbackFcn(app, @URJoint5SpinnerValueChanged, true);
            app.GridLayout13_5 = uigridlayout(app.GridLayout11);
            app.Joint6SpinnerLabel = uilabel(app.GridLayout13_5);
            app.Joint6SpinnerLabel.Text = 'Joint 6';
            app.URJoint6Spinner = uispinner(app.GridLayout13_5);
            app.URJoint6Spinner.Step = 0.01;
            app.URJoint6Spinner.ValueDisplayFormat = '%0.4f';
            app.URJoint6Spinner.ValueChangedFcn = createCallbackFcn(app, @URJoint6SpinnerValueChanged, true);
            app.CartesianTeachTab = uitab(app.TabGroup);
            app.CartesianTeachTab.Title = 'Cartesian Teach';
            app.GridLayout_6 = uigridlayout(app.CartesianTeachTab);
            app.GridLayout2_6 = uigridlayout(app.GridLayout_6);
            app.URCartesianTeachPanel = uipanel(app.GridLayout2_6);
            app.URCartesianTeachPanel.Title = 'UR3';
            app.GridLayout8 = uigridlayout(app.URCartesianTeachPanel);
            app.URJointVelocitiesPanel = uipanel(app.GridLayout8);
            app.URJointVelocitiesPanel.Title = 'Joint Velocities';
            app.GridLayout10 = uigridlayout(app.URJointVelocitiesPanel);
            app.URCartesianTeachJointVelocity1 = uilabel(app.GridLayout10);
            app.URCartesianTeachJointVelocity1.Text = 'Joint 1: 0.000';
            app.URCartesianTeachJointVelocity2 = uilabel(app.GridLayout10);
            app.URCartesianTeachJointVelocity2.Text = 'Joint 2: 0.000';
            app.URCartesianTeachJointVelocity3 = uilabel(app.GridLayout10);
            app.URCartesianTeachJointVelocity3.Text = 'Joint 3: 0.000';
            app.URCartesianTeachJointVelocity4 = uilabel(app.GridLayout10);
            app.URCartesianTeachJointVelocity4.Text = 'Joint 4: 0.000';
            app.URCartesianTeachJointVelocity5 = uilabel(app.GridLayout10);
            app.URCartesianTeachJointVelocity5.Text = 'Joint 5: 0.000';
            app.URCartesianTeachJointVelocity6 = uilabel(app.GridLayout10);
            app.URCartesianTeachJointVelocity6.Text = 'Joint 6: 0.000';
            app.URCartesianTeachStartButton = uibutton(app.GridLayout8, 'push');
            app.URCartesianTeachStartButton.ButtonPushedFcn = createCallbackFcn(app, @URCartesianTeachStartButtonPushed, true);
            app.URCartesianTeachStartButton.Text = 'Start';
            app.URCartesianTeachEndEffectorTransform = uipanel(app.GridLayout8);
            app.URCartesianTeachEndEffectorTransform.Title = 'End Effector Transform';
            app.GridLayout3_8 = uigridlayout(app.URCartesianTeachEndEffectorTransform);
            app.URCartesianTeachEndEffectorTransform11 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform11.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform12 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform12.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform13 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform13.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform14 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform14.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform21 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform21.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform22 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform22.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform23 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform23.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform24 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform24.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform31 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform31.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform32 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform32.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform33 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform33.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform34 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform34.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform41 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform41.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform42 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform42.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform43 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform43.Text = 'Loading';
            app.URCartesianTeachEndEffectorTransform44 = uilabel(app.GridLayout3_8);
            app.URCartesianTeachEndEffectorTransform44.Text = 'Loading';
            app.URCartesianTeachDesiredTransform = uipanel(app.GridLayout8);
            app.URCartesianTeachDesiredTransform.Title = 'Desired Transform';
            app.GridLayout3_11 = uigridlayout(app.URCartesianTeachDesiredTransform);
            app.URCartesianTeachDesiredTransform11 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform11.Step = 0.01;
            app.URCartesianTeachDesiredTransform11.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform11.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform11.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform11ValueChanged, true);
            app.URCartesianTeachDesiredTransform12 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform12.Step = 0.01;
            app.URCartesianTeachDesiredTransform12.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform12.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform12.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform12ValueChanged, true);
            app.URCartesianTeachDesiredTransform13 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform13.Step = 0.01;
            app.URCartesianTeachDesiredTransform13.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform13.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform13.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform13ValueChanged, true);
            app.URCartesianTeachDesiredTransform14 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform14.Step = 0.01;
            app.URCartesianTeachDesiredTransform14.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform14.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform14ValueChanged, true);
            app.URCartesianTeachDesiredTransform21 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform21.Step = 0.01;
            app.URCartesianTeachDesiredTransform21.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform21.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform21.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform21ValueChanged, true);
            app.URCartesianTeachDesiredTransform22 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform22.Step = 0.01;
            app.URCartesianTeachDesiredTransform22.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform22.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform22.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform22ValueChanged, true);
            app.URCartesianTeachDesiredTransform23 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform23.Step = 0.01;
            app.URCartesianTeachDesiredTransform23.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform23.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform23.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform23ValueChanged, true);
            app.URCartesianTeachDesiredTransform24 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform24.Step = 0.01;
            app.URCartesianTeachDesiredTransform24.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform24.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform24ValueChanged, true);
            app.URCartesianTeachDesiredTransform31 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform31.Step = 0.01;
            app.URCartesianTeachDesiredTransform31.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform31.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform31.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform31ValueChanged, true);
            app.URCartesianTeachDesiredTransform32 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform32.Step = 0.01;
            app.URCartesianTeachDesiredTransform32.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform32.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform32.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform32ValueChanged, true);
            app.URCartesianTeachDesiredTransform33 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform33.Step = 0.01;
            app.URCartesianTeachDesiredTransform33.Limits = [-1 1];
            app.URCartesianTeachDesiredTransform33.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform33.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform33ValueChanged, true);
            app.URCartesianTeachDesiredTransform34 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform34.Step = 0.01;
            app.URCartesianTeachDesiredTransform34.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform34.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform34ValueChanged, true);
            app.URCartesianTeachDesiredTransform41 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform41.Step = 0.01;
            app.URCartesianTeachDesiredTransform41.Limits = [0 0];
            app.URCartesianTeachDesiredTransform41.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform41.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform41ValueChanged, true);
            app.URCartesianTeachDesiredTransform42 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform42.Step = 0.01;
            app.URCartesianTeachDesiredTransform42.Limits = [0 0];
            app.URCartesianTeachDesiredTransform42.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform42.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform42ValueChanged, true);
            app.URCartesianTeachDesiredTransform43 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform43.Step = 0.01;
            app.URCartesianTeachDesiredTransform43.Limits = [0 0];
            app.URCartesianTeachDesiredTransform43.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform43.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform43ValueChanged, true);
            app.URCartesianTeachDesiredTransform44 = uispinner(app.GridLayout3_11);
            app.URCartesianTeachDesiredTransform44.Step = 0.01;
            app.URCartesianTeachDesiredTransform44.Limits = [1 1];
            app.URCartesianTeachDesiredTransform44.ValueDisplayFormat = '%0.2f';
            app.URCartesianTeachDesiredTransform44.ValueChangedFcn = createCallbackFcn(app, @URCartesianTeachDesiredTransform44ValueChanged, true);
            app.URCartesianTeachDesiredTransform44.Value = 1;
            app.GridLayout7 = uigridlayout(app.GridLayout_6);
            app.TM5CartesianTeachPanel = uipanel(app.GridLayout7);
            app.TM5CartesianTeachPanel.Title = 'TM5';
            app.GridLayout8_2 = uigridlayout(app.TM5CartesianTeachPanel);
            app.URJointVelocitiesPanel_2 = uipanel(app.GridLayout8_2);
            app.URJointVelocitiesPanel_2.Title = 'Joint Velocities';
            app.GridLayout10_2 = uigridlayout(app.URJointVelocitiesPanel_2);
            app.TM5CartesianTeachJointVelocity1 = uilabel(app.GridLayout10_2);
            app.TM5CartesianTeachJointVelocity1.Text = 'Joint 1: 0.000';
            app.TM5CartesianTeachJointVelocity2 = uilabel(app.GridLayout10_2);
            app.TM5CartesianTeachJointVelocity2.Text = 'Joint 2: 0.000';
            app.TM5CartesianTeachJointVelocity3 = uilabel(app.GridLayout10_2);
            app.TM5CartesianTeachJointVelocity3.Text = 'Joint 3: 0.000';
            app.TM5CartesianTeachJointVelocity4 = uilabel(app.GridLayout10_2);
            app.TM5CartesianTeachJointVelocity4.Text = 'Joint 4: 0.000';
            app.TM5CartesianTeachJointVelocity5 = uilabel(app.GridLayout10_2);
            app.TM5CartesianTeachJointVelocity5.Text = 'Joint 5: 0.000';
            app.TM5CartesianTeachJointVelocity6 = uilabel(app.GridLayout10_2);
            app.TM5CartesianTeachJointVelocity6.Text = 'Joint 6: 0.000';
            app.TM5CartesianTeachStartButton = uibutton(app.GridLayout8_2, 'push');
            app.TM5CartesianTeachStartButton.ButtonPushedFcn = createCallbackFcn(app, @TM5CartesianTeachStartButtonPushed, true);
            app.TM5CartesianTeachStartButton.Text = 'Start';
            app.TM5CartesianTeachEndEffectorTransform = uipanel(app.GridLayout8_2);
            app.TM5CartesianTeachEndEffectorTransform.Title = 'End Effector Transform';
            app.GridLayout3_9 = uigridlayout(app.TM5CartesianTeachEndEffectorTransform);
            app.TM5CartesianTeachEndEffectorTransform11 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform11.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform12 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform12.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform13 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform13.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform14 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform14.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform21 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform21.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform22 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform22.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform23 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform23.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform24 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform24.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform31 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform31.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform32 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform32.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform33 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform33.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform34 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform34.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform41 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform41.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform42 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform42.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform43 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform43.Text = 'Loading';
            app.TM5CartesianTeachEndEffectorTransform44 = uilabel(app.GridLayout3_9);
            app.TM5CartesianTeachEndEffectorTransform44.Text = 'Loading';
            app.TM5CartesianTeachDesiredTransform = uipanel(app.GridLayout8_2);
            app.TM5CartesianTeachDesiredTransform.Title = 'Desired Transform';
            app.GridLayout3_10 = uigridlayout(app.TM5CartesianTeachDesiredTransform);
            app.TM5CartesianTeachDesiredTransform11 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform11.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform11.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform11.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform11.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform11ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform12 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform12.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform12.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform12.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform12.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform12ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform13 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform13.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform13.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform13.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform13.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform13ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform14 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform14.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform14.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform14.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform14ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform21 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform21.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform21.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform21.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform21.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform21ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform22 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform22.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform22.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform22.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform22.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform22ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform23 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform23.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform23.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform23.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform23.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform23ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform24 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform24.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform24.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform24.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform24ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform31 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform31.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform31.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform31.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform31.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform31ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform32 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform32.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform32.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform32.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform32.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform32ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform33 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform33.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform33.Limits = [-1 1];
            app.TM5CartesianTeachDesiredTransform33.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform33.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform33ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform34 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform34.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform34.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform34.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform34ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform41 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform41.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform41.Limits = [0 0];
            app.TM5CartesianTeachDesiredTransform41.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform41.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform41ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform42 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform42.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform42.Limits = [0 0];
            app.TM5CartesianTeachDesiredTransform42.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform42.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform42ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform43 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform43.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform43.Limits = [0 0];
            app.TM5CartesianTeachDesiredTransform43.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform43.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform43ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform44 = uispinner(app.GridLayout3_10);
            app.TM5CartesianTeachDesiredTransform44.Step = 0.01;
            app.TM5CartesianTeachDesiredTransform44.Limits = [1 1];
            app.TM5CartesianTeachDesiredTransform44.ValueDisplayFormat = '%0.2f';
            app.TM5CartesianTeachDesiredTransform44.ValueChangedFcn = createCallbackFcn(app, @TM5CartesianTeachDesiredTransform44ValueChanged, true);
            app.TM5CartesianTeachDesiredTransform44.Value = 1;
            app.EvEChessTab = uitab(app.TabGroup);
            app.EvEChessTab.Title = 'EvE Chess';
            app.GridLayout14 = uigridlayout(app.EvEChessTab);
            app.EVEAxis = uiaxes(app.GridLayout14);
            title(app.EVEAxis, 'Title')
            xlabel(app.EVEAxis, 'X')
            ylabel(app.EVEAxis, 'Y')
            zlabel(app.EVEAxis, 'Z')
            app.EVEControlPanel = uipanel(app.GridLayout14);
            app.EVEControlPanel.Title = 'Control Panel';
            app.GridLayout15 = uigridlayout(app.EVEControlPanel);
            app.EVEStartButton = uibutton(app.GridLayout15, 'push');
            app.EVEStartButton.ButtonPushedFcn = createCallbackFcn(app, @EVEStartButtonPushed, true);
            app.EVEStartButton.Text = 'START';
            app.EVEeStopButton = uibutton(app.GridLayout15, 'push');
            app.EVEeStopButton.ButtonPushedFcn = createCallbackFcn(app, @EVEeStopButtonPushed, true);
            app.EVEeStopButton.Text = 'E STOP';
            app.EVELightCurtainTestButton = uibutton(app.GridLayout15, 'push');
            app.EVELightCurtainTestButton.ButtonPushedFcn = createCallbackFcn(app, @EVELightCurtainTestButtonPushed, true);
            app.EVELightCurtainTestButton.Text = 'LIGHT CURTAIN TEST';
            app.EVECollisonTestButton = uibutton(app.GridLayout15, 'push');
            app.EVECollisonTestButton.ButtonPushedFcn = createCallbackFcn(app, @EVECollisonTestButtonPushed, true);
            app.EVECollisonTestButton.Text = 'COLLISION TEST';
            app.PvEChessTab = uitab(app.TabGroup);
            app.PvEChessTab.Title = 'PvE Chess';
            app.GridLayout14_2 = uigridlayout(app.PvEChessTab);
            app.UIAxes2 = uiaxes(app.GridLayout14_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ControlPanel_2 = uipanel(app.GridLayout14_2);
            app.ControlPanel_2.Title = 'Control Panel';
            app.GridLayout15_2 = uigridlayout(app.ControlPanel_2);
            app.PVEStartButton = uibutton(app.GridLayout15_2, 'push');
            app.PVEStartButton.ButtonPushedFcn = createCallbackFcn(app, @PVEStartButtonPushed, true);
            app.PVEStartButton.Text = 'START';
            app.ESTOPButton_2 = uibutton(app.GridLayout15_2, 'push');
            app.ESTOPButton_2.Text = 'E STOP';
            app.LIGHTCURTAINTESTButton_2 = uibutton(app.GridLayout15_2, 'push');
            app.LIGHTCURTAINTESTButton_2.Text = 'LIGHT CURTAIN TEST';
            app.COLLISIONTESTButton_2 = uibutton(app.GridLayout15_2, 'push');
            app.COLLISIONTESTButton_2.Text = 'COLLISION TEST';
            app.RealRobotControlPanel = uipanel(app.GridLayout14_2);
            app.RealRobotControlPanel.Title = 'Real Robot Control';
            app.GridLayout17 = uigridlayout(app.RealRobotControlPanel);
            app.PVEUserInput = uitextarea(app.GridLayout17);
            app.SendMoveButton = uibutton(app.GridLayout17, 'push');
            app.SendMoveButton.ButtonPushedFcn = createCallbackFcn(app, @SendMoveButtonPushed, true);
            app.SendMoveButton.Text = 'Send Move';
            app.RealRobotControlPanel_2 = uipanel(app.GridLayout14_2);
            app.RealRobotControlPanel_2.Title = 'Real Robot Control';
            app.PVERealRobotControlSwitch = uiswitch(app.RealRobotControlPanel_2, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end